classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        StateEditField       matlab.ui.control.EditField
        StateEditFieldLabel  matlab.ui.control.Label
        StartButton          matlab.ui.control.Button
        Image                matlab.ui.control.Image
    end


    properties (Access = private)
        img_cross = imread('C:\Users\Peter\Desktop\CGX_git\img\cross.png');
        img_right = imread('C:\Users\Peter\Desktop\CGX_git\img\right.png');
        img_left  = imread('C:\Users\Peter\Desktop\CGX_git\img\left.png');
        img_black = imread('C:\Users\Peter\Desktop\CGX_git\img\black.png');
        mouse
    end
    
    methods (Access = public)
        
        function results = func1(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(570, 1050);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
            
        end
        
        function results = func2(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
            app.mouse.mouseMove(1430, 157);
            app.mouse.mousePress(InputEvent.BUTTON1_DOWN_MASK);    %left click press
            app.mouse.mouseRelease(InputEvent.BUTTON1_DOWN_MASK);   %left click release
          
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import java.awt.Robot;
            import java.awt.event.*;
            app.mouse = Robot;
        end

        % Button pushed function: StartButton
        function tmp(app, event)

            app.Image.ImageSource = app.img_cross; app.StateEditField.Value = "Cross"; app.func1();
            pause(2)
            app.Image.ImageSource = app.img_right; app.StateEditField.Value = "Right"; 
            pause(2)
            app.Image.ImageSource = app.img_left; app.StateEditField.Value = "Left"; 
            pause(2)
            app.Image.ImageSource = app.img_black; app.StateEditField.Value = "None"; app.func2();
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'black.png');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @tmp, true);
            app.StartButton.Text = 'Start';
            app.StateEditFieldLabel = uilabel(app.UIFigure);
            app.StateEditFieldLabel.Text = 'State';
            app.StateEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end